classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19AppUIFigure         matlab.ui.Figure
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoplotButtonGroup      matlab.ui.container.ButtonGroup
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        AveragedofdaysSlider       matlab.ui.control.Slider
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        StatesListBox              matlab.ui.control.ListBox
        StateorRegionLabel         matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountriesLabel             matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties
        AllData 
        EditedData
        Countries
        Dates
        StartDate
        EndDate
        TimePoints
        ListOfCountries
        Pairs
        CurrentState
        CurrentFirstIndex
        CurrentLastIndex
        CurrentStateIndex
        Averages
        Datatoplot
    end
    
    methods (Access = private)

        function getData(app)
            app.EditedData = app.AllData;
            for i = 2:size(app.AllData,1)
                if isempty(app.AllData{i,2})
                    app.EditedData{i,2} = 'All';
                end
            end
            app.EditedData{end+1,1} = 'Global';
            app.EditedData{end,2} = 'All';  
            for i = 3:size(app.EditedData,2)
                a= [0, 0];
                for j = 2:size(app.EditedData,1)-1
                    a = app.EditedData{j,i} + a;
                end
                app.EditedData{end, i} = a;
            end


            CountriesCell = cell(1,size(app.EditedData, 1));
            skips = 0;
            pairs1 = cell(size(app.EditedData, 1),2);
            for i = 2:size(app.EditedData, 1)
                if skips > 0
                    skips = skips - 1;
                    continue;
                end
                first_ind = i;
                last_ind = i;
                if i + 1 < size(app.EditedData, 1) && i > 2 && strcmp(app.EditedData{i+1,1}, app.EditedData{i,1})
                    first_ind = i;
                    j = i;
                    while strcmp(app.EditedData{j+1,1}, app.EditedData{j,1})
                        skips = skips + 1;
                        j = j + 1;
                    end
                    last_ind = j;
                        
                end
                CountriesCell{i-1} = {Country2(app.EditedData, app.EditedData{first_ind, 1}, first_ind, last_ind)};
                pairs1(i) = {[first_ind, last_ind]};
            end
            
            pairs1 = pairs1(~cellfun(@isempty, pairs1));
            app.Countries = CountriesCell;
            for i = 1:size(pairs1,1)
                pairs1(i,2) = {app.EditedData{pairs1{i}(1),1}};
            end
            app.Pairs = pairs1;
            app.ListOfCountries = app.Pairs(:,2);

        end
        
        function SettingDates(app)
            
            app.Dates = app.EditedData(1,3:end);
            app.TimePoints = datetime(app.Dates, 'InputFormat', 'MM/dd/yy');
            app.TimePoints.Format = 'MM/dd/yy';
            
        end
        
        function DefaultSettings(app)
            app.CountryListBox.Layout;
            app.CountryListBox.Items = [ {'Global'}; app.ListOfCountries(1:end-1) ];
            app.CountryListBox.Value = 'Global';
            scroll(app.CountryListBox, 'top');
            app.StatesListBox.Items = {'All'};
            app.StatesListBox.Value = 'All';
            app.CurrentFirstIndex = 340;
            app.CurrentStateIndex = 1;
            app.Averages = 1;
            app.CasesButton.Value = true;
            app.CumulativeButton.Value = true;
            app.plotData();
        end
        
        function plotData(app)
            switch app.DatatoplotButtonGroup.SelectedObject
                case app.CasesButton
                    switch app.OptionButtonGroup.SelectedObject
                        case app.DailyButton
                            cla(app.UIAxes, 'reset');
                            DataToPlot = movmean(app.Countries{1,app.CurrentFirstIndex-1}{1,1}.StatesCasesDaily{app.CurrentStateIndex,2}, app.Averages);
                            bar(app.UIAxes, app.TimePoints, DataToPlot);
                            title(app.UIAxes, 'Number of Daily Cases', 'FontSize', 18);
                            xlabel(app.UIAxes, 'Dates');
                            app.UIAxes.YAxis.Exponent = 0;
                            ytickformat(app.UIAxes, '%.0f');
                            
                        case app.CumulativeButton
                            cla(app.UIAxes, 'reset');
                            DataToPlot = movmean(app.Countries{1,app.CurrentFirstIndex-1}{1,1}.StatesCasesCum{app.CurrentStateIndex,2}, app.Averages);
                            bar(app.UIAxes, app.TimePoints, DataToPlot);
                            title(app.UIAxes, 'Number of Cumulative Cases', 'FontSize', 18);
                            xlabel(app.UIAxes, 'Dates');
                            app.UIAxes.YAxis.Exponent = 0;
                            ytickformat(app.UIAxes, '%.0f');
                    end
                case app.DeathsButton
                    switch app.OptionButtonGroup.SelectedObject
                        case app.DailyButton
                            cla(app.UIAxes, 'reset');
                            DataToPlot = movmean(app.Countries{1,app.CurrentFirstIndex-1}{1,1}.StatesDeathsDaily{app.CurrentStateIndex,2}, app.Averages);
                            plot(app.UIAxes, app.TimePoints, DataToPlot, 'r', 'LineWidth', 1);
                            title(app.UIAxes, 'Number of Daily Deaths', 'FontSize', 18);
                            xlabel(app.UIAxes, 'Dates');
                            app.UIAxes.YAxis.Exponent = 0;
                            ytickformat(app.UIAxes, '%.0f');
                            
                        case app.CumulativeButton
                            cla(app.UIAxes, 'reset');
                            DataToPlot = movmean(app.Countries{1,app.CurrentFirstIndex-1}{1,1}.StatesDeathsCum{app.CurrentStateIndex,2}, app.Averages);
                            plot(app.UIAxes, app.TimePoints, DataToPlot, 'r', 'LineWidth', 1);
                            title(app.UIAxes, 'Number of Cumulative Deaths', 'FontSize', 18);
                            xlabel(app.UIAxes, 'Dates');
                            app.UIAxes.YAxis.Exponent = 0;
                            ytickformat(app.UIAxes, '%.0f');
                            
                    end
                case app.BothButton
                    switch app.OptionButtonGroup.SelectedObject
                        case app.DailyButton
                            cla(app.UIAxes, 'reset');
                            yyaxis(app.UIAxes, 'left')
                            DataToPlot2 = movmean(app.Countries{1,app.CurrentFirstIndex-1}{1,1}.StatesCasesDaily{app.CurrentStateIndex,2}, app.Averages);
                            bar(app.UIAxes, app.TimePoints, DataToPlot2);
                            title(app.UIAxes, 'Number of Daily Cases and Number of Daily Deaths', 'FontSize', 18);
                            ylabel(app.UIAxes, 'Number of Daily Cases');
                            app.UIAxes.YAxis(1).Exponent = 0;
                            ytickformat(app.UIAxes, '%.0f');
                            hold(app.UIAxes, 'off');

                            yyaxis(app.UIAxes, 'right') 
                            DataToPlot = movmean(app.Countries{1,app.CurrentFirstIndex-1}{1,1}.StatesDeathsDaily{app.CurrentStateIndex,2}, app.Averages);
                            plot(app.UIAxes, app.TimePoints, DataToPlot, 'r', 'LineWidth', 1);
                            hold(app.UIAxes, 'on');
                            xlabel(app.UIAxes, 'Dates');
                            ylabel(app.UIAxes, 'Number of Daily Deaths');
                            app.UIAxes.YAxis(2).Exponent = 0;


                        case app.CumulativeButton
                            cla(app.UIAxes, 'reset');
                            yyaxis(app.UIAxes, 'left')
                            DataToPlot2 = movmean(app.Countries{1,app.CurrentFirstIndex-1}{1,1}.StatesCasesCum{app.CurrentStateIndex,2}, app.Averages);
                            bar(app.UIAxes, app.TimePoints, DataToPlot2);
                            title(app.UIAxes, 'Number of Cumulative Cases and Number of Cumulative Deaths', 'FontSize', 18);
                            ylabel(app.UIAxes, 'Number of Cumulative Cases');
                            app.UIAxes.YAxis(1).Exponent = 0;
                            ytickformat(app.UIAxes, '%.0f');
                            hold(app.UIAxes, 'off');

                            yyaxis(app.UIAxes, 'right')
                            DataToPlot = movmean(app.Countries{1,app.CurrentFirstIndex-1}{1,1}.StatesDeathsCum{app.CurrentStateIndex,2}, app.Averages);
                            plot(app.UIAxes, app.TimePoints, DataToPlot, 'r', 'LineWidth', 1);
                            hold(app.UIAxes, 'on');
                            xlabel(app.UIAxes, 'Dates');
                            ylabel(app.UIAxes, 'Number of Cumulative Deaths');
                            app.UIAxes.YAxis(2).Exponent = 0;


                    end
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data; 
            app.AllData = covid_data;
            getData(app);
            SettingDates(app);
            DefaultSettings(app);
        end

        % Close request function: COVID19AppUIFigure
        function COVID19AppUIFigureCloseRequest(app, event)
            delete(figure);
            delete(app);
            
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            country = app.CountryListBox.Value;
            index = find(strcmp(app.Pairs, country)) - 196;
            app.CurrentFirstIndex = app.Pairs{index,1}(1);
            app.CurrentLastIndex = app.Pairs{index,1}(2);
            app.CurrentStateIndex = 1;
            app.plotData();
            ListOfStates = {app.EditedData{app.CurrentFirstIndex:app.CurrentLastIndex,2}};
            app.StatesListBox.Items = ListOfStates;
            
        end

        % Value changed function: StatesListBox
        function StatesListBoxValueChanged(app, event)
            app.CurrentState = app.StatesListBox.Value;
            states = app.StatesListBox.Items;
            app.CurrentStateIndex = find(strcmp(states, app.CurrentState));
            app.plotData();
            
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            app.AveragedofdaysSlider.Value = round(app.AveragedofdaysSlider.Value);
            app.Averages = app.AveragedofdaysSlider.Value;
            app.plotData()
        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
            app.plotData();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.plotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19AppUIFigure = uifigure('Visible', 'off');
            app.COVID19AppUIFigure.Name = 'COVID-19 App';
            app.COVID19AppUIFigure.CloseRequestFcn = createCallbackFcn(app, @COVID19AppUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.COVID19AppUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CountriesLabel = uilabel(app.COVID19AppUIFigure);
            app.CountriesLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.COVID19AppUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionLabel = uilabel(app.COVID19AppUIFigure);
            app.StateorRegionLabel.Text = 'State or Region';
            app.StatesListBox = uilistbox(app.COVID19AppUIFigure);
            app.StatesListBox.ValueChangedFcn = createCallbackFcn(app, @StatesListBoxValueChanged, true);
            app.AveragedofdaysSliderLabel = uilabel(app.COVID19AppUIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AveragedofdaysSlider = uislider(app.COVID19AppUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoplotButtonGroup = uibuttongroup(app.COVID19AppUIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.OptionButtonGroup = uibuttongroup(app.COVID19AppUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            % Show the figure after all components are created
            app.COVID19AppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19AppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19AppUIFigure)
        end
    end
end